classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImagesUIFigure  matlab.ui.Figure
        BWaxes          matlab.ui.control.UIAxes
        OpenBut         matlab.ui.control.Button
        histBut         matlab.ui.control.Button
        EditField       matlab.ui.control.NumericEditField
        EditField_2     matlab.ui.control.NumericEditField
        Button_4        matlab.ui.control.Button
        Slider          matlab.ui.control.Slider
        Slider2         matlab.ui.control.Slider
        IMaxes          matlab.ui.control.UIAxes
        Button_5        matlab.ui.control.Button
        Label           matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: OpenBut
        function OpenButPushed(app, event)
            global rgbIm;
            global grayIm;
            [filename, pathname] = uigetfile('*.*', 'Open file');
            rgbIm = imread(strcat(pathname,filename));   
            grayIm = rgb2gray(rgbIm);
            imshow(rgbIm, 'Parent',app.IMaxes);
            imshow(grayIm, 'Parent',app.BWaxes);
            
        end

        % Button pushed function: histBut
        function histButPushed(app, event)
            global grayIm;
            imhist(grayIm);           
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            global binIm;
            edema = 0;
            noEdema = 0;
            procent = 0;
            [n,m] = size(binIm);
            for i=1:n            
                  for s=1:m      
                    if binIm(i,s) == 1 
                        noEdema = noEdema + 1;    
                    else
                        edema = edema + 1;   
                 end        
               end 
            end 
            procent = (noEdema/(n*m))*100;
            app.Label.Text = num2str(procent);           
        end

        % Value changed function: Slider, Slider2
        function SliderValueChanged(app, event)
           global grayIm;     
           global binIm;
            value1 = app.Slider.Value;
            value2 = app.Slider2.Value;
            binIm = roicolor(grayIm, value1, value2);
            imshow(binIm, 'Parent',app.BWaxes);
            app.EditField.Value = value1;
            app.EditField_2.Value = value2;
        end

        % Value changed function: EditField, EditField_2
        function EditFieldValueChanged(app, event)
            global grayIm;
            global binIm;
            value1 = app.EditField.Value;
            value2 = app.EditField_2.Value;
            binIm = roicolor(grayIm, value1, value2);
            imshow(binIm, 'Parent',app.BWaxes);
            app.Slider.Value = value1;
            app.Slider2.Value = value2;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            global rgbIm;
            global grayIm;
            rgbIm=imcrop(rgbIm);
            grayIm = rgb2gray(rgbIm);
            imshow(rgbIm, 'Parent',app.IMaxes);
            imshow(grayIm, 'Parent',app.BWaxes);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImagesUIFigure = uifigure;
            app.ImagesUIFigure.Name = 'Images';
            app.BWaxes = uiaxes(app.ImagesUIFigure);
            app.BWaxes.XTick = [];
            app.BWaxes.YTick = [];
            app.OpenBut = uibutton(app.ImagesUIFigure, 'push');
            app.OpenBut.ButtonPushedFcn = createCallbackFcn(app, @OpenButPushed, true);
            app.OpenBut.Text = 'Выбрать фото';
            app.histBut = uibutton(app.ImagesUIFigure, 'push');
            app.histBut.ButtonPushedFcn = createCallbackFcn(app, @histButPushed, true);
            app.histBut.Text = 'Гистограмма';
            app.EditField = uieditfield(app.ImagesUIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.ImagesUIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2.Value = 250;
            app.Button_4 = uibutton(app.ImagesUIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = 'Считать';
            app.Slider = uislider(app.ImagesUIFigure);
            app.Slider.Limits = [0 250];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider2 = uislider(app.ImagesUIFigure);
            app.Slider2.Limits = [0 250];
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider2.Value = 250;
            app.IMaxes = uiaxes(app.ImagesUIFigure);
            app.IMaxes.XTick = [];
            app.IMaxes.YTick = [];
            app.Button_5 = uibutton(app.ImagesUIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = 'Выделить область';
            app.Label = uilabel(app.ImagesUIFigure);
            app.Label.Text = 'Процент';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImagesUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImagesUIFigure)
        end
    end
end